classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
        Panel                    matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        LevelsTab                matlab.ui.container.Tab
        ZoomOutButton            matlab.ui.control.Button
        ZoomInButton             matlab.ui.control.Button
        ResetChannelButton       matlab.ui.control.Button
        ColorChannelButtonGroup  matlab.ui.container.ButtonGroup
        ValueButton              matlab.ui.control.RadioButton
        BlueButton               matlab.ui.control.RadioButton
        GreenButton              matlab.ui.control.RadioButton
        RedButton                matlab.ui.control.RadioButton
        LowerSlider              matlab.ui.control.Slider
        UpperSlider              matlab.ui.control.Slider
        Histogram                matlab.ui.control.UIAxes
        CurvesTab                matlab.ui.container.Tab
        ImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file
        path
        
        img0
        img
        hist
    end
    
    methods (Access = private)
        
        function updateimage(app)
            
            % For corn.tif, read the second image in the file 
            file = Tiff('Andromeda.TIF','r');
            img = im2double(read(file));
            im = imadjust(img,stretchlim(img),[]);
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.Histogram, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.Histogram, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.Histogram.YLim = [0 maxcount];
                app.Histogram.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
        
        function updateHistogram(app)
            app.Histogram.XLim = [0 1];
        end
        
        function updateImage(app)
            imagesc(app.ImageAxes,app.img);
            
            app.hist = histogram(app.Histogram, app.img);
            app.updateHistogram();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(2^10);
            axis(app.ImageAxes, 'image');

            warning off
            
            % Update the image and histograms
            addpath(genpath('Functions'));
            addpath(genpath('../Images'));
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [app.file,app.path] = uigetfile('../Images/*.*');
            
            image = Tiff(fullfile(app.path,app.file),'r');
            app.img0 = im2double(read(image));
            app.img = app.img0;
            
            app.updateImage();
        end

        % Value changed function: LowerSlider
        function LowerSliderValueChanged(app, event)
            value = app.LowerSlider.Value;
            
            if value > app.UpperSlider.Value
                app.LowerSlider.Value = app.UpperSlider.Value;
            end
            
            app.img0 = stretchImage(app.img,[app.LowerSlider.Value,app.UpperSlider.Value]);
            imagesc(app.ImageAxes,app.img0);
        end

        % Value changed function: UpperSlider
        function UpperSliderValueChanged(app, event)
            value = app.UpperSlider.Value;
            
            if value < app.LowerSlider.Value
                app.UpperSlider.Value = app.LowerSlider.Value;
            end
            
            app.img0 = stretchImage(app.img,[app.LowerSlider.Value,app.UpperSlider.Value]);
            imagesc(app.ImageAxes,app.img0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LevelsTab = uitab(app.TabGroup);
            app.LevelsTab.Title = 'Levels';
            app.Histogram = uiaxes(app.LevelsTab);
            title(app.Histogram, 'Histogram')
            app.Histogram.XLim = [0 255];
            app.UpperSlider = uislider(app.LevelsTab);
            app.UpperSlider.Limits = [0 1];
            app.UpperSlider.MajorTicks = 1;
            app.UpperSlider.ValueChangedFcn = createCallbackFcn(app, @UpperSliderValueChanged, true);
            app.UpperSlider.MinorTicks = [];
            app.UpperSlider.Value = 1;
            app.LowerSlider = uislider(app.LevelsTab);
            app.LowerSlider.Limits = [0 1];
            app.LowerSlider.MajorTicks = 0;
            app.LowerSlider.ValueChangedFcn = createCallbackFcn(app, @LowerSliderValueChanged, true);
            app.LowerSlider.MinorTicks = [];
            app.ColorChannelButtonGroup = uibuttongroup(app.LevelsTab);
            app.ColorChannelButtonGroup.Title = 'Color Channel';
            app.RedButton = uiradiobutton(app.ColorChannelButtonGroup);
            app.RedButton.Text = 'Red';
            app.GreenButton = uiradiobutton(app.ColorChannelButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.ColorChannelButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.ValueButton = uiradiobutton(app.ColorChannelButtonGroup);
            app.ValueButton.Text = 'Value';
            app.ValueButton.Value = true;
            app.ResetChannelButton = uibutton(app.LevelsTab, 'push');
            app.ResetChannelButton.Text = 'Reset Channel';
            app.ZoomInButton = uibutton(app.LevelsTab, 'push');
            app.ZoomInButton.Text = 'Zoom In';
            app.ZoomOutButton = uibutton(app.LevelsTab, 'push');
            app.ZoomOutButton.Text = 'Zoom Out';
            app.CurvesTab = uitab(app.TabGroup);
            app.CurvesTab.Title = 'Curves';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end